classdef matCompare < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UITextTextLanguageoptionMenu    matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        EnglishMenu                     matlab.ui.container.Menu
        Menu_2                          matlab.ui.container.Menu
        UITable_Base                    matlab.ui.control.Table
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout4                     matlab.ui.container.GridLayout
        UITextTextMergeoptionPanel      matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        GridLayout7                     matlab.ui.container.GridLayout
        Button_Write2Bws                matlab.ui.control.Button
        Button_RightSelect              matlab.ui.control.Button
        Button_LeftSelect               matlab.ui.control.Button
        UITable_Compare                 matlab.ui.control.Table
        GridLayout5                     matlab.ui.container.GridLayout
        UITextTextOnlydifferenceButton  matlab.ui.control.StateButton
        Button_SaveData                 matlab.ui.control.Button
        UITextTextCompareButton         matlab.ui.control.Button
        GridLayout2                     matlab.ui.container.GridLayout
        UITextTextFilerightPanel        matlab.ui.container.Panel
        GridLayout_Right                matlab.ui.container.GridLayout
        UITable_RightData               matlab.ui.control.Table
        UITextTextSelectFileButton_2    matlab.ui.control.Button
        UITextTextFileleftPanel         matlab.ui.container.Panel
        GridLayout_Left                 matlab.ui.container.GridLayout
        UITable_LeftData                matlab.ui.control.Table
        UITextTextSelectFileButton      matlab.ui.control.Button
        ContextMenu                     matlab.ui.container.ContextMenu
        UITextTextByvisdiffMenu         matlab.ui.container.Menu
    end

    
    properties (Access = public)
        State   StateEnum
        UserData
        TempUI
        Configuration
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            startupFunc(app);
        end

        % Button pushed function: Button_SaveData
        function Button_SaveDataPushed(app, event)
            SaveDataButton(app, event);
        end

        % Button pushed function: UITextTextSelectFileButton
        function UITextTextSelectFileButtonPushed(app, event)
            SelectLeftButton(app, event);
        end

        % Button pushed function: UITextTextSelectFileButton_2
        function UITextTextSelectFileButton_2Pushed(app, event)
            SelectRightButton(app, event);
        end

        % Button pushed function: UITextTextCompareButton
        function UITextTextCompareButtonPushed(app, event)
            CompareButton(app, event);
        end

        % Selection changed function: UITable_Base
        function UITable_BaseSelectionChanged(app, event)
            TableSelectColor(app, event);
        end

        % Value changed function: UITextTextOnlydifferenceButton
        function UITextTextOnlydifferenceButtonValueChanged(app, event)
            OnlyDiffButton(app, event);
        end

        % Button pushed function: Button_LeftSelect
        function Button_LeftSelectPushed(app, event)
            MergeOptionButton(app, event, mergeOptionEnum.left);
        end

        % Button pushed function: Button_RightSelect
        function Button_RightSelectPushed(app, event)
            MergeOptionButton(app, event, mergeOptionEnum.right);
        end

        % Selection changed function: UITable_Compare
        function UITable_CompareSelectionChanged(app, event)
            % CompareTableSelection(app, event);
        end

        % Double-clicked callback: UITable_Compare
        function UITable_CompareDoubleClicked(app, event)
            CompareTableDoubleClicked(app, event);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            closeFunc(app, event);
            delete(app);
        end

        % Cell selection callback: UITable_Compare
        function UITable_CompareCellSelection(app, event)
            CompareTableSelection(app, event);
        end

        % Menu selected function: Menu
        function MenuSelected(app, event)
            LanguageMenuSelected(app, event, 'Chinese');
        end

        % Menu selected function: EnglishMenu
        function EnglishMenuSelected(app, event)
            LanguageMenuSelected(app, event, 'English');
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            LanguageMenuSelected(app, event, 'Japanese');
        end

        % Menu selected function: UITextTextByvisdiffMenu
        function UITextTextByvisdiffMenuSelected(app, event)
            CompareTableMenuByvisdiff(app, event);
        end

        % Button pushed function: Button_Write2Bws
        function Button_Write2BwsPushed(app, event)
            Write2BwsButton(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UITextTextLanguageoptionMenu = uimenu(app.UIFigure);
            app.UITextTextLanguageoptionMenu.Text = 'UIText.Text(''Language option'')';
            app.Menu = uimenu(app.UITextTextLanguageoptionMenu);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.Menu.Text = '中文';
            app.EnglishMenu = uimenu(app.UITextTextLanguageoptionMenu);
            app.EnglishMenu.MenuSelectedFcn = createCallbackFcn(app, @EnglishMenuSelected, true);
            app.EnglishMenu.Text = 'English';
            app.Menu_2 = uimenu(app.UITextTextLanguageoptionMenu);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '日本語';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UITextTextFileleftPanel = uipanel(app.GridLayout2);
            app.UITextTextFileleftPanel.Title = 'UIText.Text(''File left'')';
            app.GridLayout_Left = uigridlayout(app.UITextTextFileleftPanel);
            app.UITextTextSelectFileButton = uibutton(app.GridLayout_Left, 'push');
            app.UITextTextSelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @UITextTextSelectFileButtonPushed, true);
            app.UITextTextSelectFileButton.Text = 'UIText.Text(''Select File'')';
            app.UITable_LeftData = uitable(app.GridLayout_Left);
            app.UITable_LeftData.ColumnName = {'UIText.Text(''TableHeader Name'')'; 'UIText.Text(''TableHeader Class'')'; 'UIText.Text(''TableHeader Value'')'; 'UIText.Text(''TableHeader DataType'')'};
            app.UITable_LeftData.RowName = {};
            app.UITable_LeftData.SelectionType = 'row';
            app.UITable_LeftData.Enable = 'off';
            app.UITable_LeftData.Visible = 'off';
            app.UITextTextFilerightPanel = uipanel(app.GridLayout2);
            app.UITextTextFilerightPanel.Title = 'UIText.Text(''File right'')';
            app.GridLayout_Right = uigridlayout(app.UITextTextFilerightPanel);
            app.UITextTextSelectFileButton_2 = uibutton(app.GridLayout_Right, 'push');
            app.UITextTextSelectFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @UITextTextSelectFileButton_2Pushed, true);
            app.UITextTextSelectFileButton_2.Text = 'UIText.Text(''Select File'')';
            app.UITable_RightData = uitable(app.GridLayout_Right);
            app.UITable_RightData.ColumnName = {'UIText.Text(''TableHeader Name'')'; 'UIText.Text(''TableHeader Class'')'; 'UIText.Text(''TableHeader Value'')'; 'UIText.Text(''TableHeader DataType'')'};
            app.UITable_RightData.RowName = {};
            app.UITable_RightData.SelectionType = 'row';
            app.UITable_RightData.Enable = 'off';
            app.UITable_RightData.Visible = 'off';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.UITextTextCompareButton = uibutton(app.GridLayout5, 'push');
            app.UITextTextCompareButton.ButtonPushedFcn = createCallbackFcn(app, @UITextTextCompareButtonPushed, true);
            app.UITextTextCompareButton.Text = 'UIText.Text(''Compare'')';
            app.Button_SaveData = uibutton(app.GridLayout5, 'push');
            app.Button_SaveData.ButtonPushedFcn = createCallbackFcn(app, @Button_SaveDataPushed, true);
            app.Button_SaveData.Text = 'UIText.Text(''Save file'')';
            app.UITextTextOnlydifferenceButton = uibutton(app.GridLayout5, 'state');
            app.UITextTextOnlydifferenceButton.ValueChangedFcn = createCallbackFcn(app, @UITextTextOnlydifferenceButtonValueChanged, true);
            app.UITextTextOnlydifferenceButton.Text = 'UIText.Text(''Only difference'')';
            app.UITextTextMergeoptionPanel = uipanel(app.GridLayout4);
            app.UITextTextMergeoptionPanel.Title = 'UIText.Text(''Merge option'')';
            app.GridLayout6 = uigridlayout(app.UITextTextMergeoptionPanel);
            app.UITable_Compare = uitable(app.GridLayout6);
            app.UITable_Compare.ColumnName = {'UIText.Text(''TableHeader LeftName'')'; 'UIText.Text(''TableHeader RightName'')'; 'UIText.Text(''TableHeader ChangeInfo'')'; 'UIText.Text(''TableHeader MergeOption'')'};
            app.UITable_Compare.RowName = {};
            app.UITable_Compare.SelectionType = 'row';
            app.UITable_Compare.CellSelectionCallback = createCallbackFcn(app, @UITable_CompareCellSelection, true);
            app.UITable_Compare.DoubleClickedFcn = createCallbackFcn(app, @UITable_CompareDoubleClicked, true);
            app.UITable_Compare.SelectionChangedFcn = createCallbackFcn(app, @UITable_CompareSelectionChanged, true);
            app.UITable_Compare.Enable = 'off';
            app.UITable_Compare.Visible = 'off';
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.Button_LeftSelect = uibutton(app.GridLayout7, 'push');
            app.Button_LeftSelect.ButtonPushedFcn = createCallbackFcn(app, @Button_LeftSelectPushed, true);
            app.Button_LeftSelect.Text = 'UIText.Text(''Select left'')';
            app.Button_RightSelect = uibutton(app.GridLayout7, 'push');
            app.Button_RightSelect.ButtonPushedFcn = createCallbackFcn(app, @Button_RightSelectPushed, true);
            app.Button_RightSelect.Text = 'UIText.Text(''Select right'')';
            app.Button_Write2Bws = uibutton(app.GridLayout7, 'push');
            app.Button_Write2Bws.ButtonPushedFcn = createCallbackFcn(app, @Button_Write2BwsPushed, true);
            app.Button_Write2Bws.Text = 'UIText.Text(''Write result to BaseWorkSpace'')';
            app.UITable_Base = uitable(app.UIFigure);
            app.UITable_Base.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_Base.RowName = {};
            app.UITable_Base.SelectionType = 'row';
            app.UITable_Base.SelectionChangedFcn = createCallbackFcn(app, @UITable_BaseSelectionChanged, true);
            app.UITable_Base.Enable = 'off';
            app.UITable_Base.Visible = 'off';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.UITextTextByvisdiffMenu = uimenu(app.ContextMenu);
            app.UITextTextByvisdiffMenu.MenuSelectedFcn = createCallbackFcn(app, @UITextTextByvisdiffMenuSelected, true);
            app.UITextTextByvisdiffMenu.Text = 'UIText.Text(''By visdiff'')';
            % Assign app.ContextMenu
            app.UITable_Compare.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matCompare

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end